classdef AnotherVersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGCleanerUIFigure              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RunICATab                       matlab.ui.container.Tab
        SaveLocationLabel               matlab.ui.control.Label
        SaveLocationEditField           matlab.ui.control.EditField
        RunICAButton                    matlab.ui.control.Button
        FileLocationLabel               matlab.ui.control.Label
        FileLocationEditField           matlab.ui.control.EditField
        ICAFileBrowseButton             matlab.ui.control.Button
        ICASaveBrowseButton             matlab.ui.control.Button
        RunFASTERTab                    matlab.ui.container.Tab
        RunFASTERButton                 matlab.ui.control.Button
        SaveLocationEditField_2Label    matlab.ui.control.Label
        SaveLocationEditField_2         matlab.ui.control.EditField
        FileLocationEditField_2Label    matlab.ui.control.Label
        FileLocationEditField_2         matlab.ui.control.EditField
        FASTFileBrowseButton            matlab.ui.control.Button
        FASTSaveBrowseButton            matlab.ui.control.Button
        OptionsPanel                    matlab.ui.container.Panel
        DisplayDataTab                  matlab.ui.container.Tab
        InputsforTimeFrequencyPanel     matlab.ui.container.Panel
        NotIfyoupickchanneltimefrequencyinsertthechannelLabel  matlab.ui.control.Label
        inputtothechannelnumberandviceversaLabel  matlab.ui.control.Label
        ChannelNumberEditFieldLabel     matlab.ui.control.Label
        ChannelNumberEditField          matlab.ui.control.NumericEditField
        ComponentNumberEditFieldLabel   matlab.ui.control.Label
        ComponentNumberEditField        matlab.ui.control.NumericEditField
        PickOneButtonGroup              matlab.ui.container.ButtonGroup
        ChannelTimeFrequencyButton      matlab.ui.control.RadioButton
        ComponentTimeFrequencyButton    matlab.ui.control.RadioButton
        TypesofDiagramButtonGroup       matlab.ui.container.ButtonGroup
        ChannelDataButton               matlab.ui.control.RadioButton
        ChannelLocationsButton          matlab.ui.control.RadioButton
        ChannelERPButton                matlab.ui.control.RadioButton
        TimeFrequencyButton_3           matlab.ui.control.RadioButton
        RunDiagramButton                matlab.ui.control.Button
        DisplayFileBrowseButton         matlab.ui.control.Button
        DatasetNameLabel                matlab.ui.control.Label
        DatasetNameEditField            matlab.ui.control.EditField
        FileLocationEditField_3Label    matlab.ui.control.Label
        FileLocationEditField_3         matlab.ui.control.EditField
        SaveDiagramTab                  matlab.ui.container.Tab
        TypesofDiagramButtonGroup_2     matlab.ui.container.ButtonGroup
        ChannelDataButton_2             matlab.ui.control.RadioButton
        ChannelLocationsButton_2        matlab.ui.control.RadioButton
        ChannelERPButton_2              matlab.ui.control.RadioButton
        TimeFrequencyButton_4           matlab.ui.control.RadioButton
        TypesofTimeFrequencyPlotsButtonGroup  matlab.ui.container.ButtonGroup
        ChannelTimeFrequencyButton_2    matlab.ui.control.RadioButton
        ComponentTimeFrequencyButton_2  matlab.ui.control.RadioButton
        SaveDiagramButton               matlab.ui.control.Button
        SaveDiagramFileBrowseButton     matlab.ui.control.Button
        FileLocationEditField_4Label    matlab.ui.control.Label
        FileLocationEditField_4         matlab.ui.control.EditField
        SaveDiagramSaveBrowseButton_2   matlab.ui.control.Button
        SaveLocationEditField_3Label    matlab.ui.control.Label
        SaveLocationEditField_3         matlab.ui.control.EditField
    end

    
    properties (Access = private)
        runICAfilepath = '' %The file's current location for ICA
        runFASTERfilepath = '' %The file's current location for FASTER
        displayfilepath = '' %The file's current location for display
        savediagramfilepath = '' %The file's current location for saving process
        name = '' %The file's name including the .set suffix
        channelIndices = 1
        componentIndices = 1
        runICAsavepath = '' %Where the file will be saved after ICA process
        runFASTERsavepath = '' %Where the file will be saved after FASTER process
        savediagramsavepath = '' %Where the file will be saved after saving process
        typeofDiagram = 'Channel Data'
        selectedButton = 'Channel Time-Frequency'
        typeofSavedDiagram = 'Channel Data'
        selectedButtonSave = 'Channel Time-Frequency'
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ICAFileBrowseButton
        function ICAFileBrowseButtonPushed(app, event)
            app.runICAfilepath = uigetdir('D:\');
            app.FileLocationEditField.Value = app.runICAfilepath;
        end

        % Button pushed function: ICASaveBrowseButton
        function ICASaveBrowseButtonPushed(app, event)
            app.runICAsavepath  = uigetdir('D:\');
            app.SaveLocationEditField.Value = app.runICAsavepath;
        end

        % Button pushed function: RunICAButton
        function RunICAButtonPushed(app, event)
            %Get all .set files and run the ICA for each file
            myFiles = dir(fullfile(app.runICAfilepath,'*.set')); %Get all .set files in struct
            for k = 1:length(myFiles)
                app.name = myFiles(k).name;
                fprintf(1, 'Now reading %s\n', app.name);
                EEG = pop_loadset('filename',app.name,'filepath',app.runICAfilepath); 
                EEG = pop_runica(EEG, 'extended',1,'interupt','on');
                EEG = pop_saveset(EEG,'filename',app.name,'filepath',app.runICAsavepath);
            end
            app.runICAfilepath = app.runICAsavepath;
        end

        % Button pushed function: FASTFileBrowseButton
        function FASTFileBrowseButtonPushed(app, event)
            app.runFASTERfilepath  = uigetdir('D:\');
            app.FileLocationEditField_2.Value = app.runFASTERfilepath;
        end

        % Button pushed function: FASTSaveBrowseButton
        function FASTSaveBrowseButtonPushed(app, event)
            app.runFASTERsavepath  = uigetdir('D:\');
            app.SaveLocationEditField_2.Value = app.runFASTERsavepath;
        end

        % Button pushed function: RunFASTERButton
        function RunFASTERButtonPushed(app, event)
            %Get all .set files and run the FASTER for each file
            myFiles = dir(fullfile(app.runFASTERfilepath,'*.set')); %Get all .set files in struct
            for k = 1:length(myFiles)
                app.name = myFiles(k).name;
                fprintf(1, 'Now reading %s\n', app.name); 
            end
            app.runICAfilepath = app.runFASTERsavepath;
        end

        % Button pushed function: DisplayFileBrowseButton
        function DisplayFileBrowseButtonPushed(app, event)
            app.displayfilepath = uigetdir('D:\');
            app.FileLocationEditField_3.Value = app.displayfilepath;
        end

        % Value changed function: DatasetNameEditField
        function DatasetNameEditFieldValueChanged(app, event)
            app.name = app.DatasetNameEditField.Value;
        end

        % Selection changed function: TypesofDiagramButtonGroup
        function TypesofDiagramButtonGroupSelectionChanged(app, event)
            app.typeofDiagram = app.TypesofDiagramButtonGroup.SelectedObject.Text;
        end

        % Value changed function: ChannelNumberEditField
        function ChannelNumberEditFieldValueChanged(app, event)
            app.channelIndices = app.ChannelNumberEditField.Value;
        end

        % Value changed function: ComponentNumberEditField
        function ComponentNumberEditFieldValueChanged(app, event)
            app.componentIndices = app.ComponentNumberEditField.Value;
        end

        % Selection changed function: PickOneButtonGroup
        function PickOneButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.PickOneButtonGroup.SelectedObject.Text;
        end

        % Button pushed function: RunDiagramButton
        function RunDiagramButtonPushed(app, event)
            EEG = pop_loadset('filename',app.name,'filepath',app.displayfilepath);
            EEG = eeg_checkset( EEG );
            switch app.typeofDiagram
                case 'Channel Data'
                    %Channel Data
                    pop_eegplot( EEG, 1, 1, 1); 
                case 'Channel Locations'
                    %Channel locations
                    figure;
                    topoplot([],EEG.chanlocs, 'style', 'blank',  'electrodes', 'numpoint', 'chaninfo', EEG.chaninfo); 
                case 'Channel ERP' 
                    %ERP in scalp/rect.array
                    figure;
                    pop_plottopo(EEG, [1:128] , app.name, 0, 'ydir',1); 
                otherwise
                    switch app.selectedButton
                        %display time frequency
                        case 'Channel Time-Frequency'
                            % For channels
                            minfreq = 2;  % ERSP min freq 
                            maxfreq = 40; % ERSP max freq (since samplig rate is 250, try not to exceed 125 Hz
                            sigalpha = 0.05;  %signifiance level alpha (for statistics)
                            pop_saveset(EEG,'filename',app.name,'filepath',app.displayfilepath);
                            figure;
                            %EEG = eeg_checkset( EEG );
                            pop_newtimef( EEG, 1, app.channelIndices, [-1000 1996], [3 0.5] ,'topovec', app.channelIndices, 'elocs', EEG.chanlocs, 'chaninfo', EEG.chaninfo,'caption', ['Channel ' num2str(app.channelIndices)] , 'baseline',[0], 'alpha', sigalpha, 'freqs', [minfreq maxfreq], 'plotitc' , 'off', 'plotphase','off', 'padratio', 1, 'winsize', 250);
                        otherwise
                            %For components
                            minfreq = 2;  % ERSP min freq 
                            maxfreq = 40; % ERSP max freq (since samplig rate is 250, try not to exceed 125 Hz
                            sigalpha = 0.05;  %signifiance level alpha (for statistics)
                            pop_saveset(EEG,'filename',app.name,'filepath',app.displayfilepath);
                            figure;
                            %EEG = eeg_checkset( EEG );
                            pop_newtimef( EEG, 0, app.componentIndices, [-1000  1996], [3         0.5] , 'topovec', EEG.icawinv(:,app.componentIndices), 'elocs', EEG.chanlocs, 'chaninfo', EEG.chaninfo, 'caption', ['IC ' num2str(app.componentIndices)], 'baseline',[0], 'plotphase', 'off', 'padratio', 1, 'winsize', 250); 
                    end
            end
        end

        % Button pushed function: SaveDiagramFileBrowseButton
        function SaveDiagramFileBrowseButtonPushed(app, event)
            app.savediagramfilepath = uigetdir('D:\');
            app.FileLocationEditField_4.Value = app.savediagramfilepath;
        end

        % Button pushed function: SaveDiagramSaveBrowseButton_2
        function SaveDiagramSaveBrowseButton_2Pushed(app, event)
            app.savediagramsavepath = uigetdir('D:\');
            app.SaveLocationEditField_3.Value = app.savediagramsavepath;
        end

        % Selection changed function: TypesofDiagramButtonGroup_2
        function TypesofDiagramButtonGroup_2SelectionChanged(app, event)
            app.typeofSavedDiagram = app.TypesofDiagramButtonGroup_2.SelectedObject;
        end

        % Selection changed function: 
        % TypesofTimeFrequencyPlotsButtonGroup
        function TypesofTimeFrequencyPlotsButtonGroupSelectionChanged(app, event)
            app.selectedButtonSave = app.TypesofTimeFrequencyPlotsButtonGroup.SelectedObject;
        end

        % Button pushed function: SaveDiagramButton
        function SaveDiagramButtonPushed(app, event)
            %Get all .set files and run the ICA for each file
            myFiles = dir(fullfile(app.savediagramfilepath,'*.set')); %Get all .set files in struct
            for k = 1:length(myFiles)
                app.name = myFiles(k).name;
                fprintf(1, 'Now reading %s\n', app.name);
                EEG = pop_loadset('filename',app.name,'filepath',app.savediagramfilepath);
                switch app.selectedButton
                    %display time frequency
                    case 'Channel Time-Frequency' % For channels
                        minfreq = 2;  % ERSP min freq 
                        maxfreq = 40; % ERSP max freq (since samplig rate is 250, try not to exceed 125 Hz
                        sigalpha = 0.05;  %signifiance level alpha (for statistics)
                        fig = pop_newtimef(EEG, 1, app.channelIndices, [-1000 1996], [3 0.5] ,'topovec', app.channelIndices, 'elocs', EEG.chanlocs, 'chaninfo', EEG.chaninfo,'caption', ['Channel ' num2str(app.channelIndices)] , 'baseline',[0], 'alpha', sigalpha, 'freqs', [minfreq maxfreq], 'plotitc' , 'off', 'plotphase','off', 'padratio', 1, 'winsize', 250);
                    otherwise  %For components
                        minfreq = 2;  % ERSP min freq 
                        maxfreq = 40; % ERSP max freq (since samplig rate is 250, try not to exceed 125 Hz
                        sigalpha = 0.05;  %signifiance level alpha (for statistics)
                        fig = pop_newtimef( EEG, 0, app.componentIndices, [-1000  1996], [0] , 'topovec', EEG.icawinv(:,comp1), 'elocs', EEG.chanlocs, 'chaninfo', EEG.chaninfo, 'caption', ['IC ' num2str(app.componentIndices)], 'baseline',[0], 'alpha',0.01, 'freqs', [minfreq maxfreq], 'plotersp', 'on', 'plotitc' , 'off', 'mcorrect', 'fdr', 'plotphase', 'off', 'padratio', 1); 
                end
                saaveas(fig, app.savediagramsavepath, 'png');
            end
            app.savediagramfilepath = app.savediagramsavepath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGCleanerUIFigure = uifigure('Visible', 'off');
            app.EEGCleanerUIFigure.Name = 'EEG Cleaner';
            app.TabGroup = uitabgroup(app.EEGCleanerUIFigure);
            app.RunICATab = uitab(app.TabGroup);
            app.RunICATab.Title = 'Run ICA';
            app.SaveLocationLabel = uilabel(app.RunICATab);
            app.SaveLocationLabel.Text = 'Save Location :';
            app.SaveLocationEditField = uieditfield(app.RunICATab, 'text');
            app.RunICAButton = uibutton(app.RunICATab, 'push');
            app.RunICAButton.ButtonPushedFcn = createCallbackFcn(app, @RunICAButtonPushed, true);
            app.RunICAButton.Text = 'Run ICA';
            app.FileLocationLabel = uilabel(app.RunICATab);
            app.FileLocationLabel.Text = 'File Location :';
            app.FileLocationEditField = uieditfield(app.RunICATab, 'text');
            app.ICAFileBrowseButton = uibutton(app.RunICATab, 'push');
            app.ICAFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ICAFileBrowseButtonPushed, true);
            app.ICAFileBrowseButton.Text = 'Browse';
            app.ICASaveBrowseButton = uibutton(app.RunICATab, 'push');
            app.ICASaveBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ICASaveBrowseButtonPushed, true);
            app.ICASaveBrowseButton.Text = 'Browse';
            app.RunFASTERTab = uitab(app.TabGroup);
            app.RunFASTERTab.Title = 'Run FASTER';
            app.RunFASTERButton = uibutton(app.RunFASTERTab, 'push');
            app.RunFASTERButton.ButtonPushedFcn = createCallbackFcn(app, @RunFASTERButtonPushed, true);
            app.RunFASTERButton.Text = 'Run FASTER';
            app.SaveLocationEditField_2Label = uilabel(app.RunFASTERTab);
            app.SaveLocationEditField_2Label.Text = 'Save Location :';
            app.SaveLocationEditField_2 = uieditfield(app.RunFASTERTab, 'text');
            app.FileLocationEditField_2Label = uilabel(app.RunFASTERTab);
            app.FileLocationEditField_2Label.Text = 'File Location :';
            app.FileLocationEditField_2 = uieditfield(app.RunFASTERTab, 'text');
            app.FASTFileBrowseButton = uibutton(app.RunFASTERTab, 'push');
            app.FASTFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @FASTFileBrowseButtonPushed, true);
            app.FASTFileBrowseButton.Text = 'Browse';
            app.FASTSaveBrowseButton = uibutton(app.RunFASTERTab, 'push');
            app.FASTSaveBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @FASTSaveBrowseButtonPushed, true);
            app.FASTSaveBrowseButton.Text = 'Browse';
            app.OptionsPanel = uipanel(app.RunFASTERTab);
            app.OptionsPanel.Title = 'Options';
            app.DisplayDataTab = uitab(app.TabGroup);
            app.DisplayDataTab.Title = 'Display Data';
            app.InputsforTimeFrequencyPanel = uipanel(app.DisplayDataTab);
            app.InputsforTimeFrequencyPanel.Title = 'Inputs for Time-Frequency';
            app.NotIfyoupickchanneltimefrequencyinsertthechannelLabel = uilabel(app.InputsforTimeFrequencyPanel);
            app.NotIfyoupickchanneltimefrequencyinsertthechannelLabel.Tag = 'req1';
            app.NotIfyoupickchanneltimefrequencyinsertthechannelLabel.Text = '*Note: Insert the number first. If you pick channel time-frequency, insert';
            app.inputtothechannelnumberandviceversaLabel = uilabel(app.InputsforTimeFrequencyPanel);
            app.inputtothechannelnumberandviceversaLabel.Tag = 'req2';
            app.inputtothechannelnumberandviceversaLabel.Text = 'input to the channel number and vice versa.';
            app.ChannelNumberEditFieldLabel = uilabel(app.InputsforTimeFrequencyPanel);
            app.ChannelNumberEditFieldLabel.Text = 'Channel Number';
            app.ChannelNumberEditField = uieditfield(app.InputsforTimeFrequencyPanel, 'numeric');
            app.ChannelNumberEditField.Limits = [1 Inf];
            app.ChannelNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelNumberEditFieldValueChanged, true);
            app.ChannelNumberEditField.Value = 1;
            app.ComponentNumberEditFieldLabel = uilabel(app.InputsforTimeFrequencyPanel);
            app.ComponentNumberEditFieldLabel.Text = 'Component Number';
            app.ComponentNumberEditField = uieditfield(app.InputsforTimeFrequencyPanel, 'numeric');
            app.ComponentNumberEditField.Limits = [1 Inf];
            app.ComponentNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentNumberEditFieldValueChanged, true);
            app.ComponentNumberEditField.Value = 1;
            app.PickOneButtonGroup = uibuttongroup(app.InputsforTimeFrequencyPanel);
            app.PickOneButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PickOneButtonGroupSelectionChanged, true);
            app.PickOneButtonGroup.Title = 'Pick One:';
            app.ChannelTimeFrequencyButton = uiradiobutton(app.PickOneButtonGroup);
            app.ChannelTimeFrequencyButton.Tag = 'Channel Time-Frequency';
            app.ChannelTimeFrequencyButton.Text = 'Channel Time-Frequency';
            app.ChannelTimeFrequencyButton.Value = true;
            app.ComponentTimeFrequencyButton = uiradiobutton(app.PickOneButtonGroup);
            app.ComponentTimeFrequencyButton.Tag = 'Component Time-Frequency';
            app.ComponentTimeFrequencyButton.Text = 'Component Time-Frequency';
            app.TypesofDiagramButtonGroup = uibuttongroup(app.DisplayDataTab);
            app.TypesofDiagramButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypesofDiagramButtonGroupSelectionChanged, true);
            app.TypesofDiagramButtonGroup.Title = 'Types of Diagram';
            app.ChannelDataButton = uiradiobutton(app.TypesofDiagramButtonGroup);
            app.ChannelDataButton.Tag = 'Data';
            app.ChannelDataButton.Text = 'Channel Data';
            app.ChannelDataButton.Value = true;
            app.ChannelLocationsButton = uiradiobutton(app.TypesofDiagramButtonGroup);
            app.ChannelLocationsButton.Tag = 'Locations';
            app.ChannelLocationsButton.Text = 'Channel Locations';
            app.ChannelERPButton = uiradiobutton(app.TypesofDiagramButtonGroup);
            app.ChannelERPButton.Tag = 'ERP';
            app.ChannelERPButton.Text = 'Channel ERP';
            app.TimeFrequencyButton_3 = uiradiobutton(app.TypesofDiagramButtonGroup);
            app.TimeFrequencyButton_3.Tag = 'Time-Frequency';
            app.TimeFrequencyButton_3.Text = 'Time-Frequency';
            app.RunDiagramButton = uibutton(app.DisplayDataTab, 'push');
            app.RunDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @RunDiagramButtonPushed, true);
            app.RunDiagramButton.Text = 'Run Diagram';
            app.DisplayFileBrowseButton = uibutton(app.DisplayDataTab, 'push');
            app.DisplayFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayFileBrowseButtonPushed, true);
            app.DisplayFileBrowseButton.Text = 'Browse';
            app.DatasetNameLabel = uilabel(app.DisplayDataTab);
            app.DatasetNameLabel.Text = 'Dataset Name :';
            app.DatasetNameEditField = uieditfield(app.DisplayDataTab, 'text');
            app.DatasetNameEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetNameEditFieldValueChanged, true);
            app.FileLocationEditField_3Label = uilabel(app.DisplayDataTab);
            app.FileLocationEditField_3Label.Text = 'File Location :';
            app.FileLocationEditField_3 = uieditfield(app.DisplayDataTab, 'text');
            app.SaveDiagramTab = uitab(app.TabGroup);
            app.SaveDiagramTab.Title = 'Save Diagram';
            app.TypesofDiagramButtonGroup_2 = uibuttongroup(app.SaveDiagramTab);
            app.TypesofDiagramButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @TypesofDiagramButtonGroup_2SelectionChanged, true);
            app.TypesofDiagramButtonGroup_2.Title = 'Types of Diagram';
            app.ChannelDataButton_2 = uiradiobutton(app.TypesofDiagramButtonGroup_2);
            app.ChannelDataButton_2.Tag = 'Data';
            app.ChannelDataButton_2.Text = 'Channel Data';
            app.ChannelDataButton_2.Value = true;
            app.ChannelLocationsButton_2 = uiradiobutton(app.TypesofDiagramButtonGroup_2);
            app.ChannelLocationsButton_2.Tag = 'Locations';
            app.ChannelLocationsButton_2.Text = 'Channel Locations';
            app.ChannelERPButton_2 = uiradiobutton(app.TypesofDiagramButtonGroup_2);
            app.ChannelERPButton_2.Tag = 'ERP';
            app.ChannelERPButton_2.Text = 'Channel ERP';
            app.TimeFrequencyButton_4 = uiradiobutton(app.TypesofDiagramButtonGroup_2);
            app.TimeFrequencyButton_4.Tag = 'Time-Frequency';
            app.TimeFrequencyButton_4.Text = 'Time-Frequency';
            app.TypesofTimeFrequencyPlotsButtonGroup = uibuttongroup(app.TypesofDiagramButtonGroup_2);
            app.TypesofTimeFrequencyPlotsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypesofTimeFrequencyPlotsButtonGroupSelectionChanged, true);
            app.TypesofTimeFrequencyPlotsButtonGroup.Title = 'Types of Time Frequency Plots';
            app.ChannelTimeFrequencyButton_2 = uiradiobutton(app.TypesofTimeFrequencyPlotsButtonGroup);
            app.ChannelTimeFrequencyButton_2.Tag = 'Channel Time-Frequency';
            app.ChannelTimeFrequencyButton_2.Text = 'Channel Time-Frequency';
            app.ChannelTimeFrequencyButton_2.Value = true;
            app.ComponentTimeFrequencyButton_2 = uiradiobutton(app.TypesofTimeFrequencyPlotsButtonGroup);
            app.ComponentTimeFrequencyButton_2.Tag = 'Component Time-Frequency';
            app.ComponentTimeFrequencyButton_2.Text = 'Component Time-Frequency';
            app.SaveDiagramButton = uibutton(app.SaveDiagramTab, 'push');
            app.SaveDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDiagramButtonPushed, true);
            app.SaveDiagramButton.Text = 'Save Diagram';
            app.SaveDiagramFileBrowseButton = uibutton(app.SaveDiagramTab, 'push');
            app.SaveDiagramFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDiagramFileBrowseButtonPushed, true);
            app.SaveDiagramFileBrowseButton.Text = 'Browse';
            app.FileLocationEditField_4Label = uilabel(app.SaveDiagramTab);
            app.FileLocationEditField_4Label.Text = 'File Location :';
            app.FileLocationEditField_4 = uieditfield(app.SaveDiagramTab, 'text');
            app.SaveDiagramSaveBrowseButton_2 = uibutton(app.SaveDiagramTab, 'push');
            app.SaveDiagramSaveBrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveDiagramSaveBrowseButton_2Pushed, true);
            app.SaveDiagramSaveBrowseButton_2.Text = 'Browse';
            app.SaveLocationEditField_3Label = uilabel(app.SaveDiagramTab);
            app.SaveLocationEditField_3Label.Text = 'Save Location :';
            app.SaveLocationEditField_3 = uieditfield(app.SaveDiagramTab, 'text');
            % Show the figure after all components are created
            app.EEGCleanerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnotherVersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGCleanerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGCleanerUIFigure)
        end
    end
end